classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        theta % Description
        currentTime
        l
        flag
        myTimer
        tmin1
        tmin2
        thr1
        thr2
        tsec1
        tsec2
        h1
        h2
        h3
        h4
        h5
        h6
        thet
    end
    
    methods (Access = public)
        
        function  displayTime(app)
            while app.flag
                delete(app.h1);
                delete(app.h2);
                delete(app.h3);
                delete(app.h4);
                delete(app.h5);
                delete(app.h6);
                setclock(app)
                drawnow()
            end
        end
        
        function setclock(app)
            app.currentTime = fix(clock());
            app.l = 90:90+app.currentTime(6)*6;
            app.theta=90: 90+(app.currentTime(5)*6) + app.currentTime(6)/10;
            app.thet = 90:mod(90+(app.currentTime(4)*30) + (app.currentTime(5)/2), 360);
        
            app.tmin1 = -cosd(90+(app.currentTime(5)*6) + app.currentTime(6)/10);
            app.tmin2 = sind(90+(app.currentTime(5)*6) + app.currentTime(6)/10);
            app.tsec1 = -cosd(90+(app.currentTime(6)/5)*360/12);
            app.tsec2 = sind(90+(app.currentTime(6)/5)*360/12);
            app.thr1 = -cosd(90+(app.currentTime(4))*360/12 + app.currentTime(5)/2);
            app.thr2 = sind(90+(app.currentTime(4))*360/12 + app.currentTime(5)/2);
            app.h1 = plot(app.UIAxes,linspace(0,app.tmin1*1.1,6),linspace(0,app.tmin2*1.1,6),'r','LineWidth',2);
            app.h2 = plot(app.UIAxes,linspace(0,app.tsec1/1.01,6),linspace(0,app.tsec2/1.01,6),'m--','LineWidth',1);
            app.h3 = plot(app.UIAxes,linspace(0,app.thr1*1.2,6),linspace(0,app.thr2*1.2,6),'k','LineWidth',2);
            app.h6 = plot(app.UIAxes,-1.09*cosd(app.theta),1.09*sind(app.theta),'b--','LineWidth',3);
            app.h5 = plot(app.UIAxes,-1.2*cosd(app.thet),1.2*sind(app.thet),'k','LineWidth',3);
            app.h4 = plot(app.UIAxes,-cosd(app.l),sind(app.l),'g:','LineWidth',3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes,'on')
            scatter(app.UIAxes,0,0,'black','filled');
            setclock(app)
            for n=1:12
                text(app.UIAxes,-0.9*cosd(90+(n)*360/12),0.9*sind(90+(n)*360/12),num2str(n),'FontSize',16)
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.flag = true;
            displayTime(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.flag = false;
            displayTime(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end